classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ExpectednoisedBSliderLabel     matlab.ui.control.Label
        ExpectednoisedBSlider          matlab.ui.control.Slider
        SeabedXmSpinnerLabel           matlab.ui.control.Label
        SeabedXmSpinner                matlab.ui.control.Spinner
        SeabedYmSpinnerLabel           matlab.ui.control.Label
        SeabedYmSpinner                matlab.ui.control.Spinner
        InterpolationtypeListBoxLabel  matlab.ui.control.Label
        InterpolationtypeListBox       matlab.ui.control.ListBox
        AUVdepthmSpinnerLabel          matlab.ui.control.Label
        AUVdepthmSpinner               matlab.ui.control.Spinner
        XsamplesSpinnerLabel           matlab.ui.control.Label
        XsamplesSpinner                matlab.ui.control.Spinner
        YsamplesSpinnerLabel           matlab.ui.control.Label
        YsamplesSpinner                matlab.ui.control.Spinner
        SeabedmediumdepthmLabel        matlab.ui.control.Label
        SeabedmediumdepthmSpinner      matlab.ui.control.Spinner
        StartButton                    matlab.ui.control.Button
        SourceleveldBSliderLabel       matlab.ui.control.Label
        SourceleveldBSlider            matlab.ui.control.Slider
        AbsorptioncoefficientdBkmSpinnerLabel  matlab.ui.control.Label
        AbsorptioncoefficientdBkmSpinner  matlab.ui.control.Spinner
        SeabedtypeListBoxLabel         matlab.ui.control.Label
        SeabedtypeListBox              matlab.ui.control.ListBox
        SignalFrequencyHzLabel         matlab.ui.control.Label
        SignalFrequencyHzSpinner       matlab.ui.control.Spinner
        SignaldurationmsLabel          matlab.ui.control.Label
        SignaldurationmsSpinner        matlab.ui.control.Spinner
        DetectionThresholddBSpinnerLabel  matlab.ui.control.Label
        DetectionThresholddBSpinner    matlab.ui.control.Spinner
        SeabedshapeListBoxLabel        matlab.ui.control.Label
        SeabedshapeListBox             matlab.ui.control.ListBox
        NoiseonsamplingButton          matlab.ui.control.StateButton
        UsepreviousdataCheckBox        matlab.ui.control.CheckBox
        OutliersButton                 matlab.ui.control.StateButton
        SoundspeedmsSpinnerLabel       matlab.ui.control.Label
        SoundspeedmsSpinner            matlab.ui.control.Spinner
        MaximumpingrateHzSpinnerLabel  matlab.ui.control.Label
        MaximumpingrateHzSpinner       matlab.ui.control.Spinner
    end

    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            assignin('base','use_previous_data', app.UsepreviousdataCheckBox.Value);
            if app.UsepreviousdataCheckBox.Value == 0
                assignin('base','N', app.ExpectednoisedBSlider.Value);
                assignin('base','c', app.SoundspeedmsSpinner.Value);
                assignin('base','PRmax', app.MaximumpingrateHzSpinner.Value);
                assignin('base','x', app.SeabedXmSpinner.Value);
                assignin('base','y',app.SeabedYmSpinner.Value);
                %N_x is a row index -> number of samples among y-axis
                %-1 because GUI returns the number of INTERVALS
                assignin('base', 'N_x', app.YsamplesSpinner.Value - 1);
                %N_y is a column index -> number of samples among x-axis
                %-1 because GUI returns the number of INTERVALS
                assignin('base','N_y', app.XsamplesSpinner.Value - 1);
                assignin('base','z_auv', app.AUVdepthmSpinner.Value);
                assignin('base','z_base', app.SeabedmediumdepthmSpinner.Value);
                assignin('base','SL', app.SourceleveldBSlider.Value);
                assignin('base','alfa', app.AbsorptioncoefficientdBkmSpinner.Value);
                assignin('base','f', app.SignalFrequencyHzSpinner.Value);
                assignin('base','Td', app.SignaldurationmsSpinner.Value * 0.001);
                assignin('base','DT', app.DetectionThresholddBSpinner.Value);
                assignin('base','shape', app.SeabedshapeListBox.Value);
                assignin('base','noise_active', app.NoiseonsamplingButton.Value);
                assignin('base','outliers_active', app.OutliersButton.Value);
                switch (app.SeabedtypeListBox.Value)
                    case "Neutral"
                        assignin('base','seabedType', 0);
                    case "Fine sand"
                        assignin('base','seabedType', -8.3);
                    case "Clay"
                        assignin('base','seabedType', -21.8);
                    case "Sand-silt"
                        assignin('base','seabedType', -10.7);
                end
            end
            assignin('base','interpolation', app.InterpolationtypeListBox.Value);
            run("main_app.m");
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ExpectednoisedBSliderLabel = uilabel(app.UIFigure);
            app.ExpectednoisedBSliderLabel.Text = {'Expected '; 'noise (dB)'};
            app.ExpectednoisedBSlider = uislider(app.UIFigure);
            app.SeabedXmSpinnerLabel = uilabel(app.UIFigure);
            app.SeabedXmSpinnerLabel.Text = 'Seabed X (m)';
            app.SeabedXmSpinner = uispinner(app.UIFigure);
            app.SeabedXmSpinner.Limits = [50 150];
            app.SeabedXmSpinner.Value = 100;
            app.SeabedYmSpinnerLabel = uilabel(app.UIFigure);
            app.SeabedYmSpinnerLabel.Text = 'Seabed Y (m)';
            app.SeabedYmSpinner = uispinner(app.UIFigure);
            app.SeabedYmSpinner.Limits = [50 150];
            app.SeabedYmSpinner.Value = 100;
            app.InterpolationtypeListBoxLabel = uilabel(app.UIFigure);
            app.InterpolationtypeListBoxLabel.Text = 'Interpolation type';
            app.InterpolationtypeListBox = uilistbox(app.UIFigure);
            app.InterpolationtypeListBox.Items = {'Linear', 'RBF', 'Natural Neighbour', 'Nearest Neighbour', 'Kriging', 'Shepard', 'Minimum Curvature', 'Biharmonic Spline', 'Spline'};
            app.InterpolationtypeListBox.Value = 'Linear';
            app.AUVdepthmSpinnerLabel = uilabel(app.UIFigure);
            app.AUVdepthmSpinnerLabel.Text = 'AUV depth (m)';
            app.AUVdepthmSpinner = uispinner(app.UIFigure);
            app.AUVdepthmSpinner.Limits = [10 Inf];
            app.AUVdepthmSpinner.Value = 100;
            app.XsamplesSpinnerLabel = uilabel(app.UIFigure);
            app.XsamplesSpinnerLabel.Text = 'X samples';
            app.XsamplesSpinner = uispinner(app.UIFigure);
            app.XsamplesSpinner.Limits = [2 101];
            app.XsamplesSpinner.Value = 50;
            app.YsamplesSpinnerLabel = uilabel(app.UIFigure);
            app.YsamplesSpinnerLabel.Text = 'Y samples';
            app.YsamplesSpinner = uispinner(app.UIFigure);
            app.YsamplesSpinner.Limits = [2 101];
            app.YsamplesSpinner.Value = 10;
            app.SeabedmediumdepthmLabel = uilabel(app.UIFigure);
            app.SeabedmediumdepthmLabel.Text = {'Seabed medium'; 'depth (m)'};
            app.SeabedmediumdepthmSpinner = uispinner(app.UIFigure);
            app.SeabedmediumdepthmSpinner.Limits = [10 1000];
            app.SeabedmediumdepthmSpinner.Value = 300;
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SourceleveldBSliderLabel = uilabel(app.UIFigure);
            app.SourceleveldBSliderLabel.Text = {'Source'; 'level (dB)'};
            app.SourceleveldBSlider = uislider(app.UIFigure);
            app.SourceleveldBSlider.Limits = [0 250];
            app.SourceleveldBSlider.Value = 200;
            app.AbsorptioncoefficientdBkmSpinnerLabel = uilabel(app.UIFigure);
            app.AbsorptioncoefficientdBkmSpinnerLabel.Text = {'Absorption '; 'coefficient (dB/km)'};
            app.AbsorptioncoefficientdBkmSpinner = uispinner(app.UIFigure);
            app.AbsorptioncoefficientdBkmSpinner.Step = 0.01;
            app.AbsorptioncoefficientdBkmSpinner.Limits = [0 Inf];
            app.SeabedtypeListBoxLabel = uilabel(app.UIFigure);
            app.SeabedtypeListBoxLabel.Text = 'Seabed type';
            app.SeabedtypeListBox = uilistbox(app.UIFigure);
            app.SeabedtypeListBox.Items = {'Neutral', 'Clay', 'Sand-silt', 'Fine sand'};
            app.SeabedtypeListBox.Value = 'Neutral';
            app.SignalFrequencyHzLabel = uilabel(app.UIFigure);
            app.SignalFrequencyHzLabel.Text = {'Signal '; 'Frequency (Hz)'};
            app.SignalFrequencyHzSpinner = uispinner(app.UIFigure);
            app.SignalFrequencyHzSpinner.Limits = [0 100000];
            app.SignalFrequencyHzSpinner.Value = 10000;
            app.SignaldurationmsLabel = uilabel(app.UIFigure);
            app.SignaldurationmsLabel.Text = {'Signal '; 'duration (ms)'};
            app.SignaldurationmsSpinner = uispinner(app.UIFigure);
            app.SignaldurationmsSpinner.Limits = [1 100];
            app.SignaldurationmsSpinner.Value = 16;
            app.DetectionThresholddBSpinnerLabel = uilabel(app.UIFigure);
            app.DetectionThresholddBSpinnerLabel.Text = {'Detection'; 'Threshold (dB)'};
            app.DetectionThresholddBSpinner = uispinner(app.UIFigure);
            app.DetectionThresholddBSpinner.Limits = [40 150];
            app.DetectionThresholddBSpinner.Value = 90;
            app.SeabedshapeListBoxLabel = uilabel(app.UIFigure);
            app.SeabedshapeListBoxLabel.Text = 'Seabed shape';
            app.SeabedshapeListBox = uilistbox(app.UIFigure);
            app.SeabedshapeListBox.Items = {'Plane', 'Step', 'Sin product', 'Gaussian', 'Mediterranean Sea'};
            app.SeabedshapeListBox.Value = 'Plane';
            app.NoiseonsamplingButton = uibutton(app.UIFigure, 'state');
            app.NoiseonsamplingButton.Text = {'Noise '; 'on sampling'};
            app.UsepreviousdataCheckBox = uicheckbox(app.UIFigure);
            app.UsepreviousdataCheckBox.Text = 'Use previous data';
            app.OutliersButton = uibutton(app.UIFigure, 'state');
            app.OutliersButton.Text = 'Outliers';
            app.SoundspeedmsSpinnerLabel = uilabel(app.UIFigure);
            app.SoundspeedmsSpinnerLabel.Text = {'Sound '; 'speed (m/s)'};
            app.SoundspeedmsSpinner = uispinner(app.UIFigure);
            app.SoundspeedmsSpinner.Step = 10;
            app.SoundspeedmsSpinner.Limits = [1000 2000];
            app.SoundspeedmsSpinner.Value = 1500;
            app.MaximumpingrateHzSpinnerLabel = uilabel(app.UIFigure);
            app.MaximumpingrateHzSpinnerLabel.Text = {'Maximum'; 'ping rate (Hz)'};
            app.MaximumpingrateHzSpinner = uispinner(app.UIFigure);
            app.MaximumpingrateHzSpinner.Limits = [1 50];
            app.MaximumpingrateHzSpinner.Value = 20;
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end