classdef interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        OKButton                   matlab.ui.control.Button
        AreawidthEditFieldLabel    matlab.ui.control.Label
        AreawidthEditField         matlab.ui.control.NumericEditField
        XmeasuresEditFieldLabel    matlab.ui.control.Label
        XmeasuresEditField         matlab.ui.control.NumericEditField
        YmeasuresEditFieldLabel    matlab.ui.control.Label
        YmeasuresEditField         matlab.ui.control.NumericEditField
        SeabedcompositionLabel     matlab.ui.control.Label
        SeabedcompositionDropDown  matlab.ui.control.DropDown
        ArealenghtEditFieldLabel   matlab.ui.control.Label
        ArealenghtEditField        matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            assignin('base','x', app.AreawidthEditField.Value);
            assignin('base','y',app.ArealenghtEditField.Value);
            assignin('base', 'N_x', app.XmeasuresEditField.Value);
            assignin('base','N_y', app.YmeasuresEditField.Value);
            switch (app.SeabedcompositionDropDown.Value)
                case "Sand"
                    assignin('base','TS', 2);
                case "Rock"
                    assignin('base','TS', 3);
            end
            run main
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.AreawidthEditFieldLabel = uilabel(app.UIFigure);
            app.AreawidthEditFieldLabel.Text = 'Area width';
            app.AreawidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.XmeasuresEditFieldLabel = uilabel(app.UIFigure);
            app.XmeasuresEditFieldLabel.Text = 'X measures';
            app.XmeasuresEditField = uieditfield(app.UIFigure, 'numeric');
            app.YmeasuresEditFieldLabel = uilabel(app.UIFigure);
            app.YmeasuresEditFieldLabel.Text = 'Y measures';
            app.YmeasuresEditField = uieditfield(app.UIFigure, 'numeric');
            app.SeabedcompositionLabel = uilabel(app.UIFigure);
            app.SeabedcompositionLabel.Text = 'Seabed composition';
            app.SeabedcompositionDropDown = uidropdown(app.UIFigure);
            app.SeabedcompositionDropDown.Items = {'Sand', 'Rock', 'Option 3', 'Option 4'};
            app.SeabedcompositionDropDown.Value = 'Sand';
            app.ArealenghtEditFieldLabel = uilabel(app.UIFigure);
            app.ArealenghtEditFieldLabel.Text = 'Area lenght';
            app.ArealenghtEditField = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = interface

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end